classdef SessionViewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        BaseGridLayout              matlab.ui.container.GridLayout
        GridLayout4                 matlab.ui.container.GridLayout
        NeuronsPanel                matlab.ui.container.Panel
        GridLayout5                 matlab.ui.container.GridLayout
        RegionDropDown              matlab.ui.control.DropDown
        RegionDropDownLabel         matlab.ui.control.Label
        SessionPanel                matlab.ui.container.Panel
        GridLayout3                 matlab.ui.container.GridLayout
        ParadigmDropDown            matlab.ui.control.DropDown
        ParadigmDropDownLabel       matlab.ui.control.Label
        NameEditField               matlab.ui.control.EditField
        NameEditFieldLabel          matlab.ui.control.Label
        GridLayout2                 matlab.ui.container.GridLayout
        EventPanel                  matlab.ui.container.Panel
        EventGridLayout             matlab.ui.container.GridLayout
        TrialEventCheckBox          matlab.ui.control.CheckBox
        FixationCheckBox            matlab.ui.control.CheckBox
        ResponsePanel               matlab.ui.container.Panel
        ResponseGridLayout          matlab.ui.container.GridLayout
        SpikeRasterCheckBox         matlab.ui.control.CheckBox
        StimulusBehaviorPanel       matlab.ui.container.Panel
        StimulusBehaviorGridLayout  matlab.ui.container.GridLayout
        FixationTargetCheckBox      matlab.ui.control.CheckBox
        GazeCheckBox                matlab.ui.control.CheckBox
        PlotGridLayout              matlab.ui.container.GridLayout
        TimeGridLayout              matlab.ui.container.GridLayout
        SpeedDropDown               matlab.ui.control.DropDown
        PlayButton                  matlab.ui.control.Button
        PauseButton                 matlab.ui.control.Button
        SkipBackButton              matlab.ui.control.Button
        SkipForwardButton           matlab.ui.control.Button
        TimeSlider                  matlab.ui.control.Slider
        TimeEditField               matlab.ui.control.NumericEditField
        EventUIAxes                 matlab.ui.control.UIAxes
        StimUIAxes                  matlab.ui.control.UIAxes
        RespUIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        %% Data
        Fn string
        Session spiky.ephys.Session
        Info spiky.ephys.SessionInfo
        Minos spiky.minos.MinosInfo
        Spikes spiky.core.Spikes
        Video spiky.minos.ScreenCapture
        HasVideo logical

        %% Player
        IsPlaying logical = false
        Timer timer
        CurrentTime double
        FrameRate double = 30

        %% Handles
        HInteractable matlab.graphics.Graphics
        HFrame matlab.graphics.primitive.Image

        %% Drawers
        RespDrawer spiky.app.Drawer
        EventDrawers spiky.app.Drawer
        StimDrawers spiky.app.Drawer

        %% Filters
        Filters spiky.app.Filter
    end

    methods (Static)
        function radioButtonListener(source, event)
            value = event.Value;
            other = source.Parent.Children(source.Parent.Children~=source & ...
                [source.Parent.Children.Value]');
            if ~isempty(other)
                other.Value = false;
                if ~isempty(other.ValueChangedFcn)
                    feval(other.ValueChangedFcn, other, struct(Value=false, PreviousValue=true, ...
                        Source=other, EventName="ValueChanged"));
                end
            % elseif ~value
            %     source.Value = true;
            end
        end
    end
    
    methods (Access = public)
        function switchStatus(app, enabled)
            if enabled
                status = "on";
            else
                status = "off";
            end
            for ii = 1:numel(app.HInteractable)
                set(app.HInteractable, "Enable", status);
            end
        end
        
        function loadSession(app, fn, ses, info, minos, spikes)
            arguments
                app spiky.app.SessionViewer
                fn string
                ses spiky.ephys.Session = spiky.ephys.Session.empty
                info spiky.ephys.SessionInfo = spiky.ephys.SessionInfo.empty
                minos spiky.minos.MinosInfo = spiky.minos.MinosInfo.empty
                spikes spiky.core.Spikes = spiky.core.Spikes.empty
            end
            app.Fn = fn;
            if isempty(ses)
                app.Session = spiky.ephys.Session(fn);
            else
                app.Session = ses;
            end
            if isempty(info)
                app.Info = app.Session.getInfo();
            else
                app.Info = info;
            end
            if isempty(minos)
                app.Minos = app.Session.getMinos();
            else
                app.Minos = minos;
            end
            if isempty(spikes)
                spikes = ses.getSpikes();
                app.Spikes = spikes.Spikes;
            else
                app.Spikes = spikes;
            end
            app.NameEditField.Value = fn;
            app.TimeSlider.Limits = [0 app.Info.Duration];
            app.TimeEditField.Limits = [0 app.Info.Duration];
            app.Video = app.Minos.getScreenCapture();
            app.HasVideo = ~isempty(app.Video.Path) && exist(app.Video.Path, "file");
            app.loadVideo();
        end
        
        function loadVideo(app)
            if ~app.HasVideo
                return
            end
            app.Video = app.Video.open();
            frame = app.Video.getFrame(0);
            app.HFrame = image(app.StimUIAxes, [0 1], [1 0], frame);
            uistack(app.HFrame, "bottom");
            app.StimUIAxes.YDir = "normal";
            app.StimUIAxes.XLim = [0 1];
            app.StimUIAxes.YLim = [0 1];
            app.setTime(0);
            app.Timer = timer(Period=round(1/(app.FrameRate), 3), ExecutionMode="fixedRate", ...
                BusyMode="drop", TimerFcn=@(~, ~) app.updateTime(NaN));
        end

        function updateTime(app, t, updateFrame)
            arguments
                app spiky.app.SessionViewer
                t double = NaN
                updateFrame logical = true
            end
            if ~isgraphics(app.HFrame)
                return
            end
            if updateFrame
                if isnan(t)
                    app.HFrame.CData = app.Video.getNextFrame(app.Video.CurrentTime+app.Timer.Period);
                else
                    app.HFrame.CData = app.Video.getFrame(t);
                end
            else
                app.Video.CurrentTime = t;
            end
            if isnan(t)
                t = app.Video.CurrentTime;
            end
            app.setTime(t);
            app.applyToDrawer(@(x) x.update(t));
        end

        function setTime(app, t)
            arguments
                app spiky.app.SessionViewer
                t double = NaN
            end
            app.CurrentTime = t;
            app.TimeEditField.Value = round(t, 2);
            app.TimeSlider.Value = t;
        end

        function play(app)
            if app.IsPlaying
                return
            end
            app.IsPlaying = true;
            app.PlayButton.Visible = false;
            app.PauseButton.Visible = true;
            app.Timer.start();
        end

        function pause(app)
            if ~app.IsPlaying
                return
            end
            app.IsPlaying = false;
            app.Timer.stop();
            app.PlayButton.Visible = true;
            app.PauseButton.Visible = false;
            app.applyToDrawer(@(x) x.update(app.CurrentTime))
        end

        function togglePlay(app)
            if app.IsPlaying
                app.pause();
            else
                app.play();
            end
        end

        function skip(app, forward)
            t = app.Video.CurrentTime;
            if ~forward
                t = t-0.5;
                if t<0
                    t = 0;
                end
            else
                t = t+0.5;
                if t>app.Info.Duration
                    t = app.Info.Duration;
                end
            end
            app.updateTime(t, ~app.IsPlaying);
        end

        function speed(app, value)
            running = app.Timer.Running;
            app.Timer.stop;
            app.Timer.Period = round(1/(app.Video.FrameRate*value), 3);
            if strcmp(running, "on")
                app.Timer.start;
            end
        end

        function applyToDrawer(app, func)
            for ii = 1:numel(app.StimDrawers)
                func(app.StimDrawers(ii))
            end
            for ii = 1:numel(app.EventDrawers)
                func(app.EventDrawers(ii))
            end
            for ii = 1:numel(app.RespDrawer)
                func(app.RespDrawer(ii))
            end
        end

        function toButtonGroup(app, hParent)
            h = findall(hParent, "-property", "ValueChangedFcn");
            h.addlistener("ValueChanged", @app.radioButtonListener);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, fn, ses, info, minos, spikes)
            arguments
                app spiky.app.SessionViewer
                fn string = string.empty
                ses spiky.ephys.Session = spiky.ephys.Session.empty
                info spiky.ephys.SessionInfo = spiky.ephys.SessionInfo.empty
                minos spiky.minos.MinosInfo = spiky.minos.MinosInfo.empty
                spikes spiky.core.Spikes = spiky.core.Spikes.empty
            end
            app.HInteractable = findall(app.UIFigure, "-property", "Enable");
            if isempty(ses)
                return
            end
            if isempty(fn)
                fn = ses.Name;
            end
            app.loadSession(fn, ses, info, minos, spikes);
            disableDefaultInteractivity(app.StimUIAxes);
            disableDefaultInteractivity(app.RespUIAxes);
            app.toButtonGroup(app.ResponseGridLayout);
            app.StimDrawers = [spiky.app.GazeDrawer(app, app.GazeCheckBox)
                spiky.app.FixationTargetDrawer(app, app.FixationTargetCheckBox)];
            app.EventDrawers = [spiky.app.TrialEventDrawer(app, app.TrialEventCheckBox)
                spiky.app.FixationDrawer(app, app.FixationCheckBox)];
            app.RespDrawer = [spiky.app.SpikeRasterDrawer(app, app.SpikeRasterCheckBox)];
            app.Filters = [spiky.app.RegionFilter(app, app.RespDrawer, app.RegionDropDown)];
            xline(app.EventUIAxes, 0, "g", LineWidth=1);
            xline(app.RespUIAxes, 0, "g", LineWidth=1);
        end

        % Callback function: TimeEditField, TimeSlider
        function TimeValueChanged(app, event)
            value = event.Value;
            app.updateTime(value, ~app.IsPlaying);
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            app.play();
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            app.pause();
        end

        % Value changed function: SpeedDropDown
        function SpeedDropDownValueChanged(app, event)
            value = app.SpeedDropDown.Value;
            value = str2double(extractBefore(value, "x"));
            app.speed(value);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.Timer.stop
            app.Timer.delete
            delete(app)
        end

        % Button pushed function: SkipBackButton, SkipForwardButton
        function SkipButtonPushed(app, event)
            if event.Source==app.SkipBackButton
                app.skip(false)
            elseif event.Source==app.SkipForwardButton
                app.skip(true)
            else
                return
            end
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            m = event.Modifier;
            switch key
                case 'space'
                    app.togglePlay();
                case 'leftarrow'
                    app.skip(false);
                case 'rightarrow'
                    app.skip(true);
                case 'comma'
                    if app.SpeedDropDown.ValueIndex>1
                        app.SpeedDropDown.ValueIndex = app.SpeedDropDown.ValueIndex-1;
                        app.SpeedDropDownValueChanged([]);
                    end
                case 'period'
                    if app.SpeedDropDown.ValueIndex<numel(app.SpeedDropDown.Items)
                        app.SpeedDropDown.ValueIndex = app.SpeedDropDown.ValueIndex+1;
                        app.SpeedDropDownValueChanged([]);
                    end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.BaseGridLayout = uigridlayout(app.UIFigure);
            app.PlotGridLayout = uigridlayout(app.BaseGridLayout);
            app.RespUIAxes = uiaxes(app.PlotGridLayout);
            app.RespUIAxes.XLim = [-1 2];
            app.RespUIAxes.YDir = 'reverse';
            app.RespUIAxes.YTick = [];
            app.StimUIAxes = uiaxes(app.PlotGridLayout);
            app.StimUIAxes.XAxisLocation = 'origin';
            app.StimUIAxes.XTick = [];
            app.StimUIAxes.YAxisLocation = 'origin';
            app.StimUIAxes.YTick = [];
            app.EventUIAxes = uiaxes(app.PlotGridLayout);
            app.EventUIAxes.XLim = [-1 2];
            app.EventUIAxes.YDir = 'reverse';
            app.EventUIAxes.XTick = [-1 -0.5 0 0.5 1 1.5 2];
            app.EventUIAxes.YTick = [];
            app.TimeGridLayout = uigridlayout(app.PlotGridLayout);
            app.TimeEditField = uieditfield(app.TimeGridLayout, 'numeric');
            app.TimeEditField.ValueDisplayFormat = '%05.2f';
            app.TimeEditField.ValueChangedFcn = createCallbackFcn(app, @TimeValueChanged, true);
            app.TimeSlider = uislider(app.TimeGridLayout);
            app.TimeSlider.Limits = [0 1];
            app.TimeSlider.MajorTicks = [];
            app.TimeSlider.MajorTickLabels = {};
            app.TimeSlider.ValueChangingFcn = createCallbackFcn(app, @TimeValueChanged, true);
            app.TimeSlider.MinorTicks = [0 0.005 0.01 0.015 0.02 0.025 0.03 0.035 0.04 0.045 0.05 0.055 0.06 0.065 0.07 0.075 0.08 0.085 0.09 0.095 0.1 0.105 0.11 0.115 0.12 0.125 0.13 0.135 0.14 0.145];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
            app.SkipForwardButton = uibutton(app.TimeGridLayout, 'push');
            app.SkipForwardButton.ButtonPushedFcn = createCallbackFcn(app, @SkipButtonPushed, true);
            app.SkipForwardButton.Icon = fullfile(pathToMLAPP, 'IconsWhite', 'skip-forward.svg');
            app.SkipForwardButton.Text = '';
            app.SkipBackButton = uibutton(app.TimeGridLayout, 'push');
            app.SkipBackButton.ButtonPushedFcn = createCallbackFcn(app, @SkipButtonPushed, true);
            app.SkipBackButton.Icon = fullfile(pathToMLAPP, 'IconsWhite', 'skip-back.svg');
            app.SkipBackButton.Text = '';
            app.PauseButton = uibutton(app.TimeGridLayout, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Icon = fullfile(pathToMLAPP, 'IconsWhite', 'pause.svg');
            app.PauseButton.Visible = 'off';
            app.PauseButton.Text = '';
            app.PlayButton = uibutton(app.TimeGridLayout, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Icon = fullfile(pathToMLAPP, 'IconsWhite', 'play.svg');
            app.PlayButton.Text = '';
            app.SpeedDropDown = uidropdown(app.TimeGridLayout);
            app.SpeedDropDown.Items = {'0.1x', '0.25x', '0.5x', '1x'};
            app.SpeedDropDown.ValueChangedFcn = createCallbackFcn(app, @SpeedDropDownValueChanged, true);
            app.SpeedDropDown.Value = '1x';
            app.GridLayout2 = uigridlayout(app.BaseGridLayout);
            app.StimulusBehaviorPanel = uipanel(app.GridLayout2);
            app.StimulusBehaviorPanel.Title = 'Stimulus & Behavior';
            app.StimulusBehaviorGridLayout = uigridlayout(app.StimulusBehaviorPanel);
            app.GazeCheckBox = uicheckbox(app.StimulusBehaviorGridLayout);
            app.GazeCheckBox.Text = 'Gaze';
            app.GazeCheckBox.Value = true;
            app.FixationTargetCheckBox = uicheckbox(app.StimulusBehaviorGridLayout);
            app.FixationTargetCheckBox.Text = 'Fixation Target';
            app.FixationTargetCheckBox.Value = true;
            app.ResponsePanel = uipanel(app.GridLayout2);
            app.ResponsePanel.Title = 'Response';
            app.ResponseGridLayout = uigridlayout(app.ResponsePanel);
            app.SpikeRasterCheckBox = uicheckbox(app.ResponseGridLayout);
            app.SpikeRasterCheckBox.Text = 'Spike Raster';
            app.EventPanel = uipanel(app.GridLayout2);
            app.EventPanel.Title = 'Event';
            app.EventGridLayout = uigridlayout(app.EventPanel);
            app.FixationCheckBox = uicheckbox(app.EventGridLayout);
            app.FixationCheckBox.Text = 'Fixation';
            app.FixationCheckBox.Value = true;
            app.TrialEventCheckBox = uicheckbox(app.EventGridLayout);
            app.TrialEventCheckBox.Text = 'Trial Event';
            app.TrialEventCheckBox.Value = true;
            app.GridLayout4 = uigridlayout(app.BaseGridLayout);
            app.SessionPanel = uipanel(app.GridLayout4);
            app.SessionPanel.Title = 'Session';
            app.GridLayout3 = uigridlayout(app.SessionPanel);
            app.NameEditFieldLabel = uilabel(app.GridLayout3);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.GridLayout3, 'text');
            app.NameEditField.Editable = 'off';
            app.ParadigmDropDownLabel = uilabel(app.GridLayout3);
            app.ParadigmDropDownLabel.Text = 'Paradigm';
            app.ParadigmDropDown = uidropdown(app.GridLayout3);
            app.ParadigmDropDown.Items = {'None'};
            app.ParadigmDropDown.Value = 'None';
            app.NeuronsPanel = uipanel(app.GridLayout4);
            app.NeuronsPanel.Title = 'Neurons';
            app.GridLayout5 = uigridlayout(app.NeuronsPanel);
            app.RegionDropDownLabel = uilabel(app.GridLayout5);
            app.RegionDropDownLabel.Text = 'Region';
            app.RegionDropDown = uidropdown(app.GridLayout5);
            app.RegionDropDown.Items = {'All'};
            app.RegionDropDown.Value = 'All';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SessionViewer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end